classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        BotonExport  matlab.ui.control.Button
        BotonStop    matlab.ui.control.Button
        UITable      matlab.ui.control.Table
        BotonPlay    matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM6", 9600, "Timeout", 5);
        end

        % Button pushed function: BotonPlay
        function BotonPlayButtonPushed(app, event)
            global parar datos
            parar = 0;
            muestras = 300; 
            voltaje = 0;
            contador = 1;
            while contador <= muestras
                if parar == 1
                    break;
                else
                    valorADC = str2double(readline(app.s));
                    const(contador) = 5;
                    voltaje(contador) = valorADC(1) * 5 / 174;
                    plot(app.UIAxes, voltaje);
                    app.UIAxes.XLim = [0 contador + 50];
                    app.UIAxes.YLim = [0 5.1];

                    tiempo(contador) = contador;
                    datos = [voltaje;tiempo];
                    app.UITable.Data = datos';
                    contador = contador+1;
                end
            end
        end

        % Button pushed function: BotonStop
        function BotonStopButtonPushed(app, event)
            global parar
            parar = 1;
        end

        % Button pushed function: BotonExport
        function BotonExportButtonPushed(app, event)
            global datos
            writematrix(datos','datos.xls');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BotonPlay = uibutton(app.UIFigure, 'push');
            app.BotonPlay.ButtonPushedFcn = createCallbackFcn(app, @BotonPlayButtonPushed, true);
            app.BotonPlay.Icon = fullfile(pathToMLAPP, 'boton-de-play.png');
            app.BotonPlay.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.BotonStop = uibutton(app.UIFigure, 'push');
            app.BotonStop.ButtonPushedFcn = createCallbackFcn(app, @BotonStopButtonPushed, true);
            app.BotonStop.Icon = fullfile(pathToMLAPP, 'detener.png');
            app.BotonStop.Text = '';
            app.BotonExport = uibutton(app.UIFigure, 'push');
            app.BotonExport.ButtonPushedFcn = createCallbackFcn(app, @BotonExportButtonPushed, true);
            app.BotonExport.Icon = fullfile(pathToMLAPP, 'exportar.png');
            app.BotonExport.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end